classdef MedicalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WeefselWijsUIFigure          matlab.ui.Figure
        Image2                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        MaxAngleFieldEditField       matlab.ui.control.NumericEditField
        MaxAngleFieldEditFieldLabel  matlab.ui.control.Label
        MinAngleFieldEditField       matlab.ui.control.NumericEditField
        MinAngleFieldEditFieldLabel  matlab.ui.control.Label
        SaveimageButton              matlab.ui.control.Button
        LoadimageButton              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image
        DicomInfo
        HorizontalLines
        CurrentFilePath
        VerticalLength
    end
    
    methods (Access = private)
        function vertical_length_cm = detectLines(app, dcmFile, angleMin, angleMax)
            % --- Pre-processing --- %
            tri_1 = dicomread(dcmFile);

            if size(tri_1, 3) == 3
                d_gray = rgb2gray(tri_1);
            else
                d_gray = tri_1;
            end

            BW = imbinarize(d_gray);
            BW = ~BW;
            edges = edge(BW,'canny',[0.03 0.15]);
            [H,theta,rho] = hough(edges);

            % get region info for calibration
            info = dicominfo(dcmFile);
            region = info.SequenceOfUltrasoundRegions;
            region_item = region.Item_1;

            % --- Hough Space Horizontal Lines (Dynamic Range) --- %
            idx_positive = (theta >= angleMin) & (theta <= angleMax);
            idx_negative = (theta >= -angleMax) & (theta <= -angleMin);
            idx_horizontal = idx_positive | idx_negative;

            H_only_horizontal = H;
            H_only_horizontal(:, ~idx_horizontal) = 0;

            NumPeaks = 10;
            P_all_horz = houghpeaks(H_only_horizontal, NumPeaks, ...
                               'Threshold', 0.2*max(H_only_horizontal(:)), ...
                               'NHoodSize', [11 11]);

            lines_all_horz = houghlines(edges, theta, rho, P_all_horz, ...
                                    'FillGap', 70, 'MinLength', 100);

            % --- Remove lines near image edges --- %
            imgSize = size(tri_1);
            imgHeight = imgSize(1);
            imgWidth = imgSize(2);

            edgeMargin = 40;   % ignore top/bottom border lines
            minSpacing = 50;   % ignored space between lines
            validLines = [];

            for i = 1:length(lines_all_horz)
                y1 = lines_all_horz(i).point1(2);
                y2 = lines_all_horz(i).point2(2);
                y_mid = mean([y1, y2]);

                if y_mid > edgeMargin && y_mid < (imgHeight - edgeMargin)
                    validLines = [validLines, lines_all_horz(i)];
                end
            end

            if isempty(validLines)
                uialert(app.WeefselWijsUIFigure,'No valid horizontal lines detected.','Line Detection Error');
                vertical_length_cm = NaN;
                return;
            end

            % sort by length
            line_lengths = arrayfun(@(L) norm(L.point1 - L.point2), validLines);
            [~, sortedIdx] = sort(line_lengths, 'descend');
            sortedLines = validLines(sortedIdx);

            % pick first (longest)
            selected = sortedLines(1);
            y_first = mean([selected.point1(2), selected.point2(2)]);

            % find second line (with enough distance)
            secondFound = false;
            for i = 2:length(sortedLines)
                y_candidate = mean([sortedLines(i).point1(2), sortedLines(i).point2(2)]);
                if abs(y_candidate - y_first) >= minSpacing
                    second = sortedLines(i);
                    secondFound = true;
                    break;
                end
            end

            if ~secondFound
                selectedLines = [selected];
            else
                selectedLines = [selected, second];
            end
            
            vertical_length_cm = 0; % initialize output

            % --- Visualization & Calculation --- %
            ax = app.UIAxes;
            imshow(tri_1,'Parent',ax); hold(ax,'on');
            ax.DataAspectRatio = [1 1 1];
            ax.XLim = [0 imgWidth];
            ax.YLim = [0 imgHeight];
            title(ax,'Selected Horizontal Lines');
            
            x_min = region_item.RegionLocationMinX0;
            x_max = region_item.RegionLocationMaxX1;

            for i = 1:length(selectedLines)
                line_data = selectedLines(i);
                y_level = mean([line_data.point1(2), line_data.point2(2)]);
                plot(ax, [x_min, x_max], [y_level, y_level], 'r', 'LineWidth', 2);
            end

            if length(selectedLines) == 2
                yA = mean([selectedLines(1).point1(2), selectedLines(1).point2(2)]);
                yB = mean([selectedLines(2).point1(2), selectedLines(2).point2(2)]);

                y_top = min(yA, yB);
                y_bottom = max(yA, yB);

                x_mid = imgWidth / 2;
                plot(ax, [x_mid, x_mid], [y_top, y_bottom], 'y', 'LineWidth', 2);

                % length in cm
                vertical_length_px = abs(y_bottom - y_top);
                pixel_spacing_mm = region_item.PhysicalDeltaY;
                vertical_length_cm = vertical_length_px * pixel_spacing_mm;

                % display
                text(ax, x_mid + 10, (y_top + y_bottom)/2, ...
                    sprintf('Length = %.3f cm', vertical_length_cm), ...
                    'Color', 'yellow', 'FontSize', 12, 'FontWeight', 'bold');
            end
            
            % Store image for saving
            app.Image = tri_1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ax = app.UIAxes;
            ax.Visible = 'off';
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            % --- Select file ---
            [file, path] = uigetfile({'*.dcm;*.png;*.jpg;*.jpeg;*.tif','Image Files'});
            if isequal(file,0)
                return;
            end
            fullPath = fullfile(path, file);

            % --- Check if it's a DICOM file ---
            if ~endsWith(file,'.dcm')
                uialert(app.WeefselWijsUIFigure,'Invalid image file.','Please Load a .dcm');
                return;
            end
            
            % Store the file path
            app.CurrentFilePath = fullPath;
            
            % Get angle parameters from UI
            angleMin = app.MinAngleFieldEditField.Value;
            angleMax = app.MaxAngleFieldEditField.Value;
            
            % Call the integrated detectLines function
            try
                vertical_length_cm = app.detectLines(fullPath, angleMin, angleMax);
                if ~isnan(vertical_length_cm) && vertical_length_cm > 0
                    fprintf('Detected vertical length: %.3f cm\n', vertical_length_cm);
                end
            catch ME
                uialert(app.WeefselWijsUIFigure, ['Error processing image: ' ME.message], 'Processing Error');
            end
        end

        % Button pushed function: SaveimageButton
        function SaveimageButtonPushed(app, event)
            if isempty(app.Image)
                uialert(app.WeefselWijsUIFigure, 'No image to save.', 'Error');
                return;
            end

            [file, path] = uiputfile({'*.png';'*.jpg';'*.tif'}, 'Save Image');
            if isequal(file, 0)
                return;
            end

            F = getframe(app.UIAxes);
            imgWithAnnotations = F.cdata;

            imwrite(imgWithAnnotations, fullfile(path, file));
        end

        % Value changed function: MinAngleFieldEditField
        function MinAngleFieldEditFieldValueChanged(app, event)
            if ~isempty(app.CurrentFilePath)
                try
                    angleMin = app.MinAngleFieldEditField.Value;
                    angleMax = app.MaxAngleFieldEditField.Value;
                    app.VerticalLength = app.detectLines(app.CurrentFilePath, angleMin, angleMax);
                catch ME
                    uialert(app.WeefselWijsUIFigure, ['Error reprocessing image: ' ME.message], 'Processing Error');
                end
            end
        end

        % Value changed function: MaxAngleFieldEditField
        function MaxAngleFieldEditFieldValueChanged(app, event)
           if ~isempty(app.CurrentFilePath)
                try
                    angleMin = app.MinAngleFieldEditField.Value;
                    angleMax = app.MaxAngleFieldEditField.Value;
                    app.VerticalLength = app.detectLines(app.CurrentFilePath, angleMin, angleMax);
                catch ME
                    uialert(app.WeefselWijsUIFigure, ['Error reprocessing image: ' ME.message], 'Processing Error');
                end
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.WeefselWijsUIFigure = uifigure('Visible', 'off');
            app.WeefselWijsUIFigure.Name = 'WeefselWijs';
            app.WeefselWijsUIFigure.Icon = fullfile(pathToMLAPP, 'WeefselWijs.png');
            app.UIAxes = uiaxes(app.WeefselWijsUIFigure);
            title(app.UIAxes, 'Dicom image')
            app.LoadimageButton = uibutton(app.WeefselWijsUIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.SaveimageButton = uibutton(app.WeefselWijsUIFigure, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Text = 'Save image';
            app.MinAngleFieldEditFieldLabel = uilabel(app.WeefselWijsUIFigure);
            app.MinAngleFieldEditFieldLabel.Text = 'Min. Angle Field';
            app.MinAngleFieldEditField = uieditfield(app.WeefselWijsUIFigure, 'numeric');
            app.MinAngleFieldEditField.Limits = [85 95];
            app.MinAngleFieldEditField.ValueChangedFcn = createCallbackFcn(app, @MinAngleFieldEditFieldValueChanged, true);
            app.MinAngleFieldEditField.Value = 85;
            app.MaxAngleFieldEditFieldLabel = uilabel(app.WeefselWijsUIFigure);
            app.MaxAngleFieldEditFieldLabel.Text = 'Max. Angle Field';
            app.MaxAngleFieldEditField = uieditfield(app.WeefselWijsUIFigure, 'numeric');
            app.MaxAngleFieldEditField.Limits = [85 95];
            app.MaxAngleFieldEditField.ValueChangedFcn = createCallbackFcn(app, @MaxAngleFieldEditFieldValueChanged, true);
            app.MaxAngleFieldEditField.Value = 95;
            app.Image3 = uiimage(app.WeefselWijsUIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Banner.png');
            app.Image2 = uiimage(app.WeefselWijsUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'WeefselWijs.png');
            % Show the figure after all components are created
            app.WeefselWijsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MedicalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WeefselWijsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WeefselWijsUIFigure)
        end
    end
end